classdef NeuroPatchbay < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NeuroPatchbayUIFigure   matlab.ui.Figure
        FileMenu                matlab.ui.container.Menu
        NewPatchbayMenu         matlab.ui.container.Menu
        SavePatchbayMenu        matlab.ui.container.Menu
        LoadPatchbayMenu        matlab.ui.container.Menu
        ActionsMenu             matlab.ui.container.Menu
        RunChannelMatchingMenu  matlab.ui.container.Menu
        GridLayout              matlab.ui.container.GridLayout
        Tree                    matlab.ui.container.Tree
        PatchbayPaletteLabel    matlab.ui.control.Label
        ComponentsLabel         matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
        ObjectContextMenu       matlab.ui.container.ContextMenu
        DeleteMenu              matlab.ui.container.Menu
        UIAxesContextMenu       matlab.ui.container.ContextMenu
        ResetViewMenu           matlab.ui.container.Menu
        ViewAllObjectsMenu      matlab.ui.container.Menu
        CleanupLayoutMenu       matlab.ui.container.Menu
    end


    properties (Access = private)
        PatchbayGraph = digraph
        ObjectList = struct
        ObjectImage
        ObjectSubgraphPlot
        ObjectText
        DragPoint
        AddingEdge
        CurrentEdge
        HomeAxes
        SelectionBox
    end

    methods (Access = private)

        %% Tree Methods
        % Constructors
        function buildComponentsTreeFromAssets(app,appPath)
            componentDirectories = fullfile(appPath,'assets','Components');
            ls = dir(componentDirectories);
            idx = strcmp('.',{ls.name}) | strcmp('..',{ls.name});
            ls(idx) = [];
            for i = 1:numel(ls)
                layer1Handle = uitreenode(app.Tree);
                layer1Handle.Text = ls(i).name;

                ls1 = dir(fullfile(ls(i).folder,ls(i).name));
                ls1([ls1.isdir]) = [];
                for j = 1:numel(ls1)
                    jsonFile = fullfile(ls1(j).folder,ls1(j).name);
                    jsonText = fileread(jsonFile);
                    jsonStruct = jsondecode(jsonText);
                    layer2Handle = uitreenode(layer1Handle);
                    layer2Handle.Text = jsonStruct.Name;
                    layer2Handle.UserData = jsonStruct;
                    
                        [~,name] = fileparts(jsonFile);
                        iconFile = fullfile(ls1(j).folder,'Icons',[name,'.png']);
                        if exist(iconFile,'file')~=0
                            layer2Handle.Icon = iconFile;
                        else
                            layer2Handle.Icon = fullfile(appPath,'assets','AppIcons','electricity-256.png');
                        end
                end
            end
            app.Tree.DoubleClickedFcn = @app.treeNodeSelected;
        end

        % Callbacks
        function treeNodeSelected(app,~,event)
            if event.InteractionInformation.Level == 2
                source = event.InteractionInformation.Node;
                newObjectName = createObjectName(app,source);
                addObjectToPalette(app,source,newObjectName)
                addObjectToList(app,source,newObjectName);
                addNodeToPatchbayGraph(app,newObjectName);
            end
        end

        function newObjectName = createObjectName(app,source)
            objectName = source.Text;
            count = 1;
            tmpName = [objectName,int2str(count)];
            while ismember(tmpName,fieldnames(app.ObjectList))
                count = count + 1;
                tmpName = regexprep(tmpName,'\d+$',int2str(count));
            end
            newObjectName = tmpName;
        end


        %% Object Methods
        % Constructors
        function addObjectToPalette(app,source,newObjectName)
            d = uiprogressdlg(app.NeuroPatchbayUIFigure,'Indeterminate','on','Message',['Adding ',newObjectName,' to palette']);
            addObjectImageToPalette(app,source,newObjectName);
            addObjectSubgraphToPalette(app,source,newObjectName);
            % addObjectTextToPalette(app,source,newObjectName)
            close(d)
        end

        function addObjectImageToPalette(app,source,objectName)
            % Get Icon Image
            [icnMat,~,alpha] = imread(source.Icon);
            icnMat = flip(icnMat,1);
            alpha = flip(alpha,1);
            x = find(sum(alpha,1)~=0);
            xl = x([1 numel(x)]);
            y = find(sum(alpha,2)~=0);
            yl = y([1 numel(y)])';

            f = figure('Visible','off');
            ax = axes(f);
            ax.Units = 'pixels';
            icn = image(ax,icnMat,'AlphaData',alpha);
            axis(ax,'equal')
            xlim(ax,xl)
            ylim(ax,yl)
            ax.YDir = 'normal';
            ax.XColor = 'none';
            ax.YColor = 'none';
            xMid = mean(icn.XData);
            yMid = mean(icn.YData);
            txt = text(ax,xMid,yMid,objectName);
            txt.FontSize = 25;
            txt.HorizontalAlignment = 'center';
            txt.VerticalAlignment = 'middle';
            txt.FontWeight = 'bold';
            if diff(yl)>diff(xl)
                txt.Rotation = 90;
            end
            txt.Color = [1 0 0];

            processedIcon = getframe(ax);
            newIcon = imresize(processedIcon.cdata,[diff(xl),diff(yl)]);
            newIcon = flip(newIcon,1);

            delete(f)

            figure(app.NeuroPatchbayUIFigure);

            % Get Properties
            xyCenter = size(newIcon,1,2)./2;
            viewCenter = sum([xlim(app.UIAxes);ylim(app.UIAxes)],2)/2;

            % Generate Object Image
            app.ObjectImage = image(app.UIAxes,newIcon,'XData',[-xyCenter(1) xyCenter(1)]+viewCenter(1),'YData',[-xyCenter(2) xyCenter(2)]+viewCenter(2));
            app.ObjectImage.Tag = objectName;
            app.ObjectImage.ButtonDownFcn = @app.clickObject;
            app.ObjectImage.ContextMenu = app.ObjectContextMenu;
        end

        function addObjectSubgraphToPalette(app,source,objectName)
            % Get Specific Object Info
            try
                outputNodeNumber = [];
                outputNodeNumber = source.UserData.Connections.Outputs.Neural.Number;
                outputSignalsPerNode = source.UserData.Connections.Outputs.Neural.SignalsPerConnector;
                outputNodeIndices = cell(outputNodeNumber,1);
                for i = 1:outputNodeNumber
                    outputNodeIndices{i} = (1:outputSignalsPerNode)+outputSignalsPerNode*(i-1);
                end
            catch
                if isempty(outputNodeNumber)
                    outputNodeNumber = 0;
                end
                outputNodeIndices = {};
            end
            
            try
                inputNodeNumber = [];
                inputNodeNumber = source.UserData.Connections.Inputs.Neural.Number;
                inputSignalsPerNode = source.UserData.Connections.Inputs.Neural.SignalsPerConnector;
                inputNodeIndices = cell(inputNodeNumber,1);
                maxIndex = max(cat(2,outputNodeIndices{:}));
                if isempty(maxIndex)
                    maxIndex = 0;
                end
                for i = 1:inputNodeNumber
                    inputNodeIndices{i} = mod((1:inputSignalsPerNode)+inputSignalsPerNode*(i-1)-1,maxIndex)+1;
                end
            catch
                if isempty(inputNodeNumber)
                    inputNodeNumber = 0;
                end
                inputNodeIndices = {};
            end
            
            % Create Subgraph NodeTable
            portIO = [repmat('I',1,inputNodeNumber) repmat('O',1,outputNodeNumber)]';
            portNumber = int2str([1:inputNodeNumber 1:outputNodeNumber]');
            portName = repmat(objectName,inputNodeNumber+outputNodeNumber,1);
            portLabel = cellstr(cat(2,portName,portIO,portNumber));
            portChannels = [inputNodeIndices; outputNodeIndices];
            nodeTable = table(portLabel,portChannels,'VariableNames',{'Name','Channels'});
            s = [];
            t = [];
            try
                map = source.UserData.Connections.Map.Neural;
                if ~isempty(map)
                    s = map(:,1);
                    t = map(:,2);
                end
            catch
            end
            subgraph = digraph(s,t,ones(size(s)),nodeTable);
            
            %InputNodes
            spacing = linspace(app.ObjectImage.XData(1),app.ObjectImage.XData(2),inputNodeNumber+2);
            spacing([1 numel(spacing)]) = [];
            xdata = spacing;
            ydata = repmat(app.ObjectImage.YData(1),1,inputNodeNumber);
            % markerArray = repmat("v",1,inputNodeNumber);
            %OutputNodes
            spacing = linspace(app.ObjectImage.XData(1),app.ObjectImage.XData(2),outputNodeNumber+2);
            spacing([1 numel(spacing)]) = [];
            xdata = [xdata spacing];
            ydata = [ydata repmat(app.ObjectImage.YData(2),1,outputNodeNumber)];
            % markerArray = [markerArray repmat("^",1,outputNodeNumber)];

            % Generate Object Subgraph
            app.ObjectSubgraphPlot = plot(app.UIAxes,subgraph,'XData',xdata,'YData',ydata);
            app.ObjectSubgraphPlot.ButtonDownFcn = @app.selectObjectPort;
            app.ObjectSubgraphPlot.NodeLabel = {};
            app.ObjectSubgraphPlot.NodeColor = [0 1 0];
            app.ObjectSubgraphPlot.EdgeColor = [0 1 0];
            app.ObjectSubgraphPlot.Marker = 'diamond';
            app.ObjectSubgraphPlot.MarkerSize = 8;
            app.ObjectSubgraphPlot.Tag = objectName;
            app.ObjectSubgraphPlot.UserData = subgraph;
            uistack(app.ObjectSubgraphPlot,'down',1)
        end

        function addObjectTextToPalette(app,~,objectName)
            xMid = mean(app.ObjectImage.XData);
            yMid = mean(app.ObjectImage.YData);
            app.ObjectText = text(app.UIAxes,xMid,yMid,objectName);
            app.ObjectText.FontSize = 20;
            app.ObjectText.HorizontalAlignment = 'center';
            app.ObjectText.VerticalAlignment = 'middle';
            app.ObjectText.Color = [1 0 0];
            app.ObjectText.Tag = objectName;
        end

        % Destructors
        function deleteSelectedObject(app)
            selectedObjectTags = getSelectedObjectTags(app);
            if isempty(selectedObjectTags)
                return
            end
            removeObjectFromPalette(app,selectedObjectTags);
            removeEdgesConnectedToObject(app,selectedObjectTags);
            removeObjectFromList(app,selectedObjectTags);
            removeNodeFromPatchbayGraph(app,selectedObjectTags);
        end

        function removeObjectFromPalette(app,objectName)
            for i = 1:numel(objectName)
                objectHandle = findobj(app.UIAxes.Children,'Tag',objectName{i});
                delete(objectHandle)
            end
        end

        % Gets
        function selectedObjectTags = getSelectedObjectTags(app)
            tmp = findobj(app.UIAxes.Children,'Selected','on');
            h = tmp(isgraphics(tmp,'Image'));
            selectedObjectTags = [];
            for i = 1:numel(h)
                selectedObjectTags = [selectedObjectTags {h.Tag}];
            end
            selectedObjectTags = unique(selectedObjectTags);
        end

        function h = getSelectedObjects(app,selectedObjectTags)
            h = [];
            for i = 1:numel(selectedObjectTags)
                h = [h; findobj(app.UIAxes.Children,'Tag',selectedObjectTags{i})];
            end
        end

        % Sets
        function deselectObjects(app)
            h = findobj(app.UIAxes.Children,'Selected','on');
            for i = 1:numel(h)
                h(i).Selected = 'off';
            end
        end

        % Callbacks
        function clickObject(app,~,event)
            selectionState = [app.NeuroPatchbayUIFigure.SelectionType,'_',int2str(event.Button)];
            switch selectionState
                case 'normal_1'
                    if strcmp(event.Source.Selected,'off')
                        deselectObjects(app)
                    end
                    event.Source.Selected = 'on';
                case 'alt_1'
                    event.Source.Selected = ~event.Source.Selected;
                case 'alt_2'
                case {'extend_1','extend_2'}
                    app.DragPoint = event.IntersectionPoint(1:2);
                    app.NeuroPatchbayUIFigure.Pointer = 'hand';
                    app.NeuroPatchbayUIFigure.WindowButtonMotionFcn = @app.panAxes;
                    app.NeuroPatchbayUIFigure.WindowButtonUpFcn = @app.panAxesDone;
                    return
                otherwise
            end
            if app.AddingEdge
                cancelAddingEdge(app)
            end
            selectedObjectTags = getSelectedObjectTags(app);
            selectedObjects = getSelectedObjects(app,selectedObjectTags);
            % ind = isgraphics(tmp,'text');
            % selectedObjects = tmp(~ind);
            % selectedObjectText = tmp(ind);
            selectedEdges = getSelectedEdgeObjects(app,selectedObjectTags);
            selectedEdgeNodes = getSelectedEdgeNodes(app,selectedEdges,selectedObjectTags);
            app.DragPoint = event.IntersectionPoint(1:2);
            app.NeuroPatchbayUIFigure.Pointer = 'fleur';
            app.NeuroPatchbayUIFigure.WindowButtonMotionFcn = {@app.dragSelection,selectedObjects,selectedEdges,selectedEdgeNodes};
            app.NeuroPatchbayUIFigure.WindowButtonUpFcn = {@app.dragSelectionDone};
        end

        function dragSelection(app,~,~,selectedObjects,selectedEdges,selectedEdgeNodes)
            moveDifference = app.UIAxes.CurrentPoint(1,1:2) - app.DragPoint;
            for i = 1:numel(selectedObjects)
                selectedObjects(i).XData = selectedObjects(i).XData + moveDifference(1);
                selectedObjects(i).YData = selectedObjects(i).YData + moveDifference(2);
            end
            % for i = 1:numel(selectedObjectText)
            %     selectedObjectText(i).Position(1:2) = selectedObjectText.Position(1:2) + moveDifference;
            % end
            for i = 1:numel(selectedEdges)
                if selectedEdgeNodes(i,1)
                    selectedEdges(i).XData(1) = selectedEdges(i).XData(1) + moveDifference(1);
                    selectedEdges(i).YData(1) = selectedEdges(i).YData(1) + moveDifference(2);
                end
                if selectedEdgeNodes(i,2)
                    selectedEdges(i).XData(2) = selectedEdges(i).XData(2) + moveDifference(1);
                    selectedEdges(i).YData(2) = selectedEdges(i).YData(2) + moveDifference(2);
                end
            end
            app.DragPoint = app.UIAxes.CurrentPoint(1,1:2);
        end

        function dragSelectionDone(app,~,~)
            app.NeuroPatchbayUIFigure.Pointer = 'arrow';
            app.NeuroPatchbayUIFigure.WindowButtonMotionFcn = [];
            app.NeuroPatchbayUIFigure.WindowButtonUpFcn = [];
        end


        %% Object List Methods
        %Constructors
        function initializeObjectList(app)
            app.ObjectList = struct;
        end
        %Destructors
        %Sets
        function addObjectToList(app,source,objectName)
            app.ObjectList.(objectName) = source.UserData;
        end

        function removeObjectFromList(app,objectName)
            for i = 1:numel(objectName)
                app.ObjectList = rmfield(app.ObjectList,objectName{i});
            end
        end

        function renameObjectInList(app,oldName,newName)
            app.ObjectList.(newName) = app.ObjectList.(oldName);
            app.ObjectList = rmfield(app.ObjectList,oldName);
        end


        %% PatchbayGraph Methods
        %Constructors
        function initializePatchbayGraph(app)
            app.PatchbayGraph = digraph;
        end
        %Destructors
        %Gets
        function rootObjectTags = getRootNodeTags(app)
            % ind = toposort(flipedge(app.PatchbayGraph));
            % subnets = conncomp(app.PatchbayGraph,'Type','weak');
            % [~,uID] = unique(subnets(ind));
            % rootObjectTags = app.PatchbayGraph.Nodes.Name(ind(uID));
            idx = find(outdegree(app.PatchbayGraph)==0);
            rootObjectTags = app.PatchbayGraph.Nodes.Name(idx);
        end

        function leafObjectTags = getLeafNodeTags(app)
            idx = find(outdegree(flipedge(app.PatchbayGraph))==0);
            leafObjectTags = app.PatchbayGraph.Nodes.Name(idx);
        end
        
        %Sets
        function addNodeToPatchbayGraph(app,objectName)
            app.PatchbayGraph = addnode(app.PatchbayGraph,objectName);
        end

        function addEdgeToPatchbayGraph(app)
            edgeNodes = strsplit(app.CurrentEdge.Tag,'-');
            app.PatchbayGraph = addedge(app.PatchbayGraph,edgeNodes{:});
        end

        function removeNodeFromPatchbayGraph(app,objectName)
            for i = 1:numel(objectName)
                app.PatchbayGraph = rmnode(app.PatchbayGraph,objectName{i});
            end
        end

        function removeEdgeFromPatchbayGraph(app,objectName)
            allEdges = cellfun(@(x) strjoin(x,'-'),table2cell(app.PatchbayGraph.Edges),'Uni',0);
            idx = find(strcmp(allEdges,objectName),1,'last');
            app.PatchbayGraph = rmedge(app.PatchbayGraph,idx);
        end


        %% Object Edges Methods
        %Constructor
        function initializeCurrentEdge(app,source,initialPoint)
            [~,portSelected] = min(sum(([source.XData;source.YData]'-initialPoint).^2,2).^(1/2));
            inputPortSelected = ~isempty(regexp(source.UserData.Nodes.Name{portSelected},'\d+I\d+$','once'));
            edgeGraph = digraph(1,2);
            app.CurrentEdge = plot(app.UIAxes,edgeGraph,'XData',repmat(source.XData(portSelected),1,2),'YData',repmat(source.YData(portSelected),1,2),'PickableParts','none');
            app.CurrentEdge.Marker = 'diamond';
            app.CurrentEdge.MarkerSize = 8;
            app.CurrentEdge.NodeColor = [1 0 0];
            app.CurrentEdge.NodeLabel = {};
            app.CurrentEdge.EdgeColor = [1 0 0];
            app.CurrentEdge.LineWidth = 3;
            app.CurrentEdge.ArrowSize = 15;
            app.CurrentEdge.ArrowPosition = 0.7;
            app.CurrentEdge.Tag = source.Tag;
            subgraphNodes = [0 0];
            movingNode = 2 - inputPortSelected;
            clickedNode = 2 - ~inputPortSelected;
            subgraphNodes(clickedNode) = portSelected;
            app.CurrentEdge.UserData = {inputPortSelected,subgraphNodes};
            uistack(app.CurrentEdge,'top')
            app.AddingEdge = 1;
            app.NeuroPatchbayUIFigure.Pointer = 'crosshair';
            app.NeuroPatchbayUIFigure.WindowButtonMotionFcn = {@app.dragNode,app.CurrentEdge,movingNode};
        end

        %Destructor
        function removeEdgesConnectedToObject(app,objectName)
            for i = 1:numel(objectName)
                edgeHandle = findobj(app.UIAxes.Children,'-regexp','Tag',objectName{i});
                delete(edgeHandle)
            end
        end

        function deleteCurrentEdgeFromPalette(app)
            delete(app.CurrentEdge)
        end

        %Gets
        function selectedEdges = getSelectedEdgeObjects(app,objectNames)
            selectedEdges = [];
            allEdges = findobj(app.UIAxes.Children,'-regexp','Tag','-');
            if isempty(allEdges)
                return
            end
            selectedObjects = [];
            for i = 1:numel(objectNames)
                selectedObjects = [selectedObjects; findobj(app.UIAxes.Children,'-regexp','Tag',objectNames{i})];
            end
            selectedEdges = intersect(allEdges,selectedObjects);
        end

        function selectedEdgeNodes = getSelectedEdgeNodes(~,selectedEdgeObjects,selectedObjectTags)
            selectedEdgeNodes = zeros(numel(selectedEdgeObjects),2);
            for i = 1:numel(selectedEdgeObjects)
                for j = 1:numel(selectedObjectTags)
                    tmp = regexp(selectedEdgeObjects(i).Tag,selectedObjectTags{j});
                    selectedEdgeNodes(i,(tmp>1)+1) = 1;
                end
            end
        end

        %Sets
        function addCurrentEdgeToPalette(app,targetSubgraph,targetNodeOfSubgraph)
            tmp = {app.CurrentEdge.Tag,targetSubgraph.Tag};
            inputPortSelected = ~isempty(regexp(targetSubgraph.UserData.Nodes.Name{targetNodeOfSubgraph},'\d+I\d+$','once'));
            if ~inputPortSelected % <-Node if SubgraphTarget is output node
                tmp = flip(tmp);
            end
            clickedNode = 2 - ~inputPortSelected;
            app.CurrentEdge.Tag = strjoin(tmp,'-');
            app.CurrentEdge.XData(clickedNode) = targetSubgraph.XData(targetNodeOfSubgraph);
            app.CurrentEdge.YData(clickedNode) = targetSubgraph.YData(targetNodeOfSubgraph);
            app.CurrentEdge.UserData{2}(clickedNode) = targetNodeOfSubgraph;
            app.CurrentEdge.PickableParts = 'all';
            app.CurrentEdge.ButtonDownFcn = @app.selectEdge;
            uistack(app.CurrentEdge,'top')
        end

        %Callbacks
        function selectObjectPort(app,source,event)
            selectionState = [app.NeuroPatchbayUIFigure.SelectionType,'_',int2str(event.Button)];
            switch selectionState
                case 'normal_1'
                    if strcmp(event.Source.Selected,'off')
                        deselectObjects(app)
                    end
                case {'extend_1','extend_2'}
                    app.DragPoint = event.IntersectionPoint(1:2);
                    app.NeuroPatchbayUIFigure.Pointer = 'hand';
                    app.NeuroPatchbayUIFigure.WindowButtonMotionFcn = @app.panAxes;
                    app.NeuroPatchbayUIFigure.WindowButtonUpFcn = @app.panAxesDone;
                    return
                case 'alt_1'
                case 'alt_2'
                case 'alt_3'
                case 'extend_3'
                otherwise
            end
            clickedPoint = event.IntersectionPoint(1:2);
            [~,idx] = min(sum(([source.XData;source.YData]'-clickedPoint).^2,2).^(1/2));
            inputPortSelected = ~isempty(regexp(source.UserData.Nodes.Name{idx},'\d+I\d+$','once'));
            if app.AddingEdge
                if ~strcmp(source.Tag,app.CurrentEdge.Tag) && app.CurrentEdge.UserData{1}~=inputPortSelected
                    dragSelectionDone(app)
                    addCurrentEdgeToPalette(app,source,idx)
                    addEdgeToPatchbayGraph(app)
                    app.AddingEdge = 0;
                    return
                else
                    cancelAddingEdge(app)
                end
            end
            initializeCurrentEdge(app,source,clickedPoint)
        end

        function selectEdge(app,source,event)
            selectionState = [app.NeuroPatchbayUIFigure.SelectionType,'_',int2str(event.Button)];
            switch selectionState
                case 'normal_1'
                    if strcmp(event.Source.Selected,'off')
                        deselectObjects(app)
                    end
                case {'extend_1','extend_2'}
                    app.DragPoint = event.IntersectionPoint(1:2);
                    app.NeuroPatchbayUIFigure.Pointer = 'hand';
                    app.NeuroPatchbayUIFigure.WindowButtonMotionFcn = @app.panAxes;
                    app.NeuroPatchbayUIFigure.WindowButtonUpFcn = @app.panAxesDone;
                    return
                case 'alt_1'
                case 'alt_2'
                case 'alt_3'
                case 'extend_3'
                otherwise
            end
            if app.AddingEdge
                return
            end
            [~,selectedNode] = min(sum(([source.XData;source.YData]'-event.IntersectionPoint(1:2)).^2,2).^(1/2));
            app.CurrentEdge = source;
            removeEdgeFromPatchbayGraph(app,app.CurrentEdge.Tag)
            tags = strsplit(app.CurrentEdge.Tag,'-');
            app.CurrentEdge.Tag = regexprep(app.CurrentEdge.Tag,tags{selectedNode},'');
            app.CurrentEdge.Tag = regexprep(app.CurrentEdge.Tag,'-','');
            app.CurrentEdge.UserData{2}(selectedNode) = 0;
            app.CurrentEdge.PickableParts = 'none';
            app.AddingEdge = 1;
            app.NeuroPatchbayUIFigure.Pointer = 'crosshair';
            app.NeuroPatchbayUIFigure.WindowButtonMotionFcn = {@app.dragNode,app.CurrentEdge,selectedNode};
        end

        function dragNode(app,~,~,callingSource,idx)
            callingSource.XData(idx) = app.UIAxes.CurrentPoint(1,1);
            callingSource.YData(idx) = app.UIAxes.CurrentPoint(1,2);
        end

        function cancelAddingEdge(app)
            deleteCurrentEdgeFromPalette(app)
            dragSelectionDone(app)
            app.AddingEdge = 0;
        end


        %% SelectionBox Methods
        %Constructor
        function generateSelectionBox(app,startingPoint)
            app.SelectionBox = line(app.UIAxes,'XData',repmat(startingPoint(1),1,5),'YData',repmat(startingPoint(2),1,5));
            app.SelectionBox.Color = [0 0 1];
            app.NeuroPatchbayUIFigure.WindowButtonMotionFcn = @app.drawingSelectionBox;
            app.NeuroPatchbayUIFigure.WindowButtonUpFcn = @app.selectionBoxDrawn;
        end

        %Destructor
        function deleteSelectionBox(app)
            delete(app.SelectionBox)
            app.NeuroPatchbayUIFigure.WindowButtonMotionFcn = [];
            app.NeuroPatchbayUIFigure.WindowButtonUpFcn = [];
        end

        %Callbacks
        function selectObjectsInsideBox(app,~,~)
            xData = app.SelectionBox.XData;
            yData = app.SelectionBox.YData;
            selectionTags = [];
            h = findobj(app.UIAxes.Children,'Type','Image');
            for i = 1:numel(h)
                if all(inpolygon(h(i).XData,h(i).YData,xData,yData))
                    selectionTags = [selectionTags {h(i).Tag}];
                end
            end
            selectionTags = unique(selectionTags);
            h = [];
            for i = 1:numel(selectionTags)
                h = [h;findobj(app.UIAxes.Children,'Tag',selectionTags{i})];
            end
            ind = isgraphics(h,'Image');
            selectedObjectImages = h(ind);
            set(selectedObjectImages,'Selected','on')
        end

        function drawingSelectionBox(app,~,~)
            currentPoint = app.UIAxes.CurrentPoint(1,1:2);
            app.SelectionBox.XData(2:3) = currentPoint(1);
            app.SelectionBox.YData(3:4) = currentPoint(2);
        end

        function selectionBoxDrawn(app,~,~)
            selectObjectsInsideBox(app)
            deleteSelectionBox(app)
        end


        %% UIAxes Methods
        %Sets
        function initializeAxes(app)
            axis(app.UIAxes,'square')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.XColor = [1 1 1];
            app.UIAxes.YColor = [1 1 1];
            app.UIAxes.Toolbar = [];
            app.HomeAxes = [xlim(app.UIAxes);ylim(app.UIAxes)];
        end

        %Callbacks
        function clickAxes(app,event)
            selectionState = [app.NeuroPatchbayUIFigure.SelectionType,'_',int2str(event.Button)];
            switch selectionState
                case 'normal_1'
                    deselectObjects(app)
                    if app.AddingEdge
                        cancelAddingEdge(app)
                    end
                    generateSelectionBox(app,event.IntersectionPoint(1:2))
                case 'alt_1'
                case 'alt_2'
                case 'alt_3'
                case {'extend_1','extend_2'}
                    app.DragPoint = event.IntersectionPoint(1:2);
                    app.NeuroPatchbayUIFigure.Pointer = 'hand';
                    app.NeuroPatchbayUIFigure.WindowButtonMotionFcn = @app.panAxes;
                    app.NeuroPatchbayUIFigure.WindowButtonUpFcn = @app.panAxesDone;
                case 'extend_3'
                otherwise
            end
        end

        function panAxes(app,~,event)
            x = event.Source.CurrentPoint(1);
            y = event.Source.CurrentPoint(2);
            tmp = event.Source.CurrentAxes.Position;
            axesPolyX = [tmp(1) tmp(1)+tmp(3) tmp(1)+tmp(3) tmp(1) tmp(1)];
            axesPolyY = [tmp(2) tmp(2) tmp(2)+tmp(4) tmp(2)+tmp(4) tmp(2)];
            if ~inpolygon(x,y,axesPolyX,axesPolyY)
                return
            end
            moveDifference = app.UIAxes.CurrentPoint(1,1:2) - app.DragPoint;
            xlim(app.UIAxes,xlim(app.UIAxes)-moveDifference(1))
            ylim(app.UIAxes,ylim(app.UIAxes)-moveDifference(2))
            app.DragPoint = app.UIAxes.CurrentPoint(1,1:2);
        end

        function panAxesDone(app,~,~)
            app.NeuroPatchbayUIFigure.Pointer = 'arrow';
            app.NeuroPatchbayUIFigure.WindowButtonMotionFcn = [];
            app.NeuroPatchbayUIFigure.WindowButtonUpFcn = [];
        end

        function zoomAxes(app,cursorPoint,InOut)
            a = xlim(app.UIAxes)-InOut*(cursorPoint(1)-mean(xlim(app.UIAxes))/2)*0.25;
            b = ylim(app.UIAxes)-InOut*(cursorPoint(2)-mean(ylim(app.UIAxes))/2)*0.25;
            xlim(app.UIAxes,a+a*0.10*InOut)
            ylim(app.UIAxes,b+b*0.10*InOut)
        end

        function fitObjectsToPalette(app)
            xMin = inf;
            xMax = -inf;
            yMin = inf;
            yMax = -inf;
            h = findobj(app.UIAxes.Children,'flat','-property','XData');
            for i = 1:numel(h)
                xMin = min([xMin h(i).XData]);
                xMax = max([xMax h(i).XData]);
                yMin = min([yMin h(i).YData]);
                yMax = max([yMax h(i).YData]);
            end
            boundingBox = [xMin,xMax;yMin,yMax];
            [~,ind] = max(diff(boundingBox,1,2));
            maxLimit = diff(boundingBox(ind,:))*1.1/2;
            midPoint = sum(boundingBox,2)./2;
            xlim(app.UIAxes,[midPoint(1)-maxLimit midPoint(1)+maxLimit])
            ylim(app.UIAxes,[midPoint(2)-maxLimit midPoint(2)+maxLimit])
        end

        %% Utility Functions
        function supergraph = getObjectPaletteSupergraph(app)
            % Generate SuperGraph of Patchbay Palette
            allGraphPlots = findobj(app.UIAxes.Children,'Type','GraphPlot');
            edgeGraphPlots = findobj(app.UIAxes.Children,'-regexp','Tag','-','Type','GraphPlot');
            nodeGraphPlots = setdiff(allGraphPlots,edgeGraphPlots);
            nodeTable = table('Size',[0 2],'VariableTypes',{'cellstr','cell'},'VariableNames',{'Name','Channels'});
            edgeTable = table('Size',[0 2],'VariableTypes',{'cell','double'},'VariableNames',{'EndNodes','Weight'});
            % Concatenate NodeTable and EdgeTable for Palette Components
            for i = 1:numel(nodeGraphPlots)
                nodeTable = [nodeTable;nodeGraphPlots(i).UserData.Nodes];
                if isempty(nodeGraphPlots(i).UserData.Edges)
                    continue
                end
                edgeTable = [edgeTable;nodeGraphPlots(i).UserData.Edges];
            end
            % Concatenate EdgeTable with edge objects connecting Palette
            % Components
            for i = 1:numel(edgeGraphPlots)
                objectTags = strsplit(edgeGraphPlots(i).Tag,'-');
                portIndices = edgeGraphPlots(i).UserData{2};
                endNodes = [];
                for j = 1:numel(objectTags)
                    tmpNodeGraph = findobj(nodeGraphPlots,'Tag',objectTags{j});
                    endNodes = [endNodes cellstr(tmpNodeGraph.UserData.Nodes.Name{portIndices(j)})];
                end
                tmpEdgeTable = table(endNodes,1,'VariableNames',{'EndNodes','Weight'});
                edgeTable = [edgeTable;tmpEdgeTable];
            end
            supergraph = digraph(edgeTable,nodeTable);
        end

        function cleanupLayout(app)
            % Get PatchbayGraph GraphPlot and find axes scales and node
            % positions
            tmpGraph = flipedge(app.PatchbayGraph);
            % d = distances(tmpGraph);
            % roots = find(all(d==Inf|d==0));
            % graphBoundingBox
            % for i = 1:numel(roots)
            %     treeIdx = find(d(roots(i),:)~=Inf);
            %     layerID = d(roots(i),treeIdx);
            %     treeList = table(tmpGraph.Nodes.Name(treeIdx),layerID(:));
            %     tmpLayer = sort(unique(layerID));
            %     treeBoundingBox
            %     treeCenter
            %     for j = 1:numel(tmpLayer)
            %         tmpTags = treeList{layerID == tmpLayer(j),1};
            %         layerSize
            %         layerCenter
            %         objectSize = zeros(2,size(tmpTags,1));
            %         objectCenter = zeros(2,size(tmpTags,1));
            %         for k = 1:size(tmpTags,1)
            %             h = findobj(app.UIAxes.Children,'Tag',tmpTags{k},'Type','Image');
            %             objectSize(:,k) = diff([h.XData;h.YData],1,2);
            %             objectCenter(:,k) = objectSize/2;
            %         end
            %         tmp = sum(objectSize(1,:),2);
            %         layerSize = [tmp+0.1*tmp;max(objectSize(2,:))];
            %         layerCenter = layerSize/2;
            %     end
            % end

            tmpFig = figure('Visible','off');
            tmpAx = axes(tmpFig);
            tmpGraphPlot = plot(tmpAx,tmpGraph);
            tmpXData = tmpGraphPlot.XData;
            tmpYData = tmpGraphPlot.YData;
            tmpAxisLimits = [xlim(tmpAx);ylim(tmpAx)];
            % [~,ind] = max(diff(tmpAxisLimits,1,2));
            % tmpMaxLimit = diff(tmpAxisLimits(ind,:));
            tmpMaxLimit = diff(tmpAxisLimits,1,2);
            tmpAxisMidpoint = mean(tmpAxisLimits,2);
            
            % Adjust UIAxes
            xMin = inf;
            xMax = -inf;
            yMin = inf;
            yMax = -inf;
            h = findobj(app.UIAxes.Children,'flat','-property','XData');
            for j = 1:numel(h)
                xMin = min([xMin h(j).XData]);
                xMax = max([xMax h(j).XData]);
                yMin = min([yMin h(j).YData]);
                yMax = max([yMax h(j).YData]);
            end
            boundingBox = [xMin,xMax;yMin,yMax];
            [~,ind] = max(diff(boundingBox,1,2));
            maxLimit = diff(boundingBox(ind,:));
            xlim(app.UIAxes,[-maxLimit maxLimit]*1.1/2) %<-10% more than maxLimit
            ylim(app.UIAxes,[-maxLimit maxLimit]*1.1/2)

            %Transform PatchbayGraph GraphPlot position data to UIAxes space
            transformedXData = (tmpXData - tmpAxisMidpoint(1))*(maxLimit/tmpMaxLimit(1));
            transformedYData = (tmpYData - tmpAxisMidpoint(2))*(maxLimit/tmpMaxLimit(2));
            
            %Move Objects on Palette into New Positions
            objectTags = tmpGraphPlot.NodeLabel;
            for i = 1:numel(objectTags)
                selectedObjects = getSelectedObjects(app,objectTags(i));
                selectedEdges = getSelectedEdgeObjects(app,objectTags(i));
                selectedEdgeNodes = getSelectedEdgeNodes(app,selectedEdges,objectTags(i));
                ind = isgraphics(selectedObjects,'Image');
                imageObjectXData  = selectedObjects(ind).XData;
                imageObjectYData  = selectedObjects(ind).YData;
                imageObjectMidpoint = mean([imageObjectXData;imageObjectYData],2)';
                moveDifference = [transformedXData(i) transformedYData(i)] - imageObjectMidpoint;
                for j = 1:numel(selectedObjects)
                    selectedObjects(j).XData = selectedObjects(j).XData + moveDifference(1);
                    selectedObjects(j).YData = selectedObjects(j).YData + moveDifference(2);
                end
                for j = 1:numel(selectedEdges)
                    if selectedEdgeNodes(j,1)
                        selectedEdges(j).XData(1) = selectedEdges(j).XData(1) + moveDifference(1);
                        selectedEdges(j).YData(1) = selectedEdges(j).YData(1) + moveDifference(2);
                    end
                    if selectedEdgeNodes(j,2)
                        selectedEdges(j).XData(2) = selectedEdges(j).XData(2) + moveDifference(1);
                        selectedEdges(j).YData(2) = selectedEdges(j).YData(2) + moveDifference(2);
                    end
                end
            end
            delete(tmpFig)

        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.NeuroPatchbayUIFigure.Visible = 'off';
            
            initializeAxes(app)
            pathToMLAPP = fileparts(mfilename('fullpath'));
            buildComponentsTreeFromAssets(app,pathToMLAPP)

            app.NeuroPatchbayUIFigure.Visible = 'on';
        end

        % Window key press function: NeuroPatchbayUIFigure
        function NeuroPatchbayUIFigureWindowKeyPress(app, event)
            key = event.Key;
            switch key
                case 'f12'
                    if any(contains(event.Modifier,'control'))
                        keyboard
                    end
                case 'delete'
                    if app.AddingEdge
                        cancelAddingEdge(app)
                    else
                        deleteSelectedObject(app)
                    end
            end
        end

        % Menu selected function: DeleteMenu
        function deleteSelectedObjectCallback(app, event)
            deleteSelectedObject(app)
        end

        % Window scroll wheel function: NeuroPatchbayUIFigure
        function NeuroPatchbayUIFigureWindowScrollWheel(app, event)
            x = event.Source.CurrentPoint(1);
            y = event.Source.CurrentPoint(2);
            tmp = event.Source.CurrentAxes.Position;
            axesPolyX = [tmp(1) tmp(1)+tmp(3) tmp(1)+tmp(3) tmp(1) tmp(1)];
            axesPolyY = [tmp(2) tmp(2) tmp(2)+tmp(4) tmp(2)+tmp(4) tmp(2)];
            if ~inpolygon(x,y,axesPolyX,axesPolyY)
                return
            end
            point = event.Source.CurrentAxes.CurrentPoint(1,1:2);
            zoomAxes(app,point,event.VerticalScrollCount)
        end

        % Menu selected function: NewPatchbayMenu
        function NewPatchbayMenuSelected(app, event)
            cla(app.UIAxes)
            initializeAxes(app)
            initializePatchbayGraph(app)
            initializeObjectList(app)
        end

        % Menu selected function: ViewAllObjectsMenu
        function ViewAllObjectsMenuSelected(app, event)
            fitObjectsToPalette(app)
        end

        % Menu selected function: ResetViewMenu
        function ResetViewMenuSelected(app, event)
            xlim(app.UIAxes,app.HomeAxes(1,:))
            ylim(app.UIAxes,app.HomeAxes(2,:))
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            clickAxes(app,event)
        end

        % Menu selected function: CleanupLayoutMenu
        function CleanupLayoutMenuSelected(app, event)
            cleanupLayout(app)
        end

        % Menu selected function: RunChannelMatchingMenu
        function RunChannelMatchingMenuSelected(app, event)
            % Generate SuperGraph of Patchbay Palette
            supergraph = getObjectPaletteSupergraph(app);

            idx = find(outdegree(app.PatchbayGraph)==0);
            rootObjectTags = app.PatchbayGraph.Nodes.Name(idx);
            idx = find(outdegree(flipedge(app.PatchbayGraph))==0);
            leafObjectTags = app.PatchbayGraph.Nodes.Name(idx);
            idx = contains(supergraph.Nodes.Name,rootObjectTags);
            supergraphRootObjectTags = supergraph.Nodes.Name(idx);
            idx = contains(supergraph.Nodes.Name,leafObjectTags);
            supergraphLeafObjectTags = supergraph.Nodes.Name(idx);
            pathCombos = cell(numel(supergraphRootObjectTags)*numel(supergraphLeafObjectTags),2);
            count = 1;
            for i = 1:numel(supergraphRootObjectTags)
                for j = 1:numel(supergraphLeafObjectTags)
                    pathCombos(count,:) = [supergraphRootObjectTags(i) supergraphLeafObjectTags(j)];
                    count = count+1;
                end
            end
            masterRootToLeafMapping = [];
            masterLeafToRootMapping = [];
            for j = 1:size(pathCombos,1)
                paths = allpaths(flipedge(supergraph),pathCombos{j,:});
                if isempty(paths)
                    continue
                end

                for k = 1:numel(paths)
                    path = paths{k};
                    [~,~,ib] = intersect(path,supergraph.Nodes.Name,'stable');
                    channelIndices = supergraph.Nodes.Channels(ib);

                    idx = contains(path,rootObjectTags) | contains(path,leafObjectTags);
                    IOChannels = channelIndices(~idx);
                    channelMaps = cell(numel(IOChannels)/2,2);
                    for i = 1:size(channelMaps,1)
                        [~,channelMaps{i,1},channelMaps{i,2}] = intersect(IOChannels{2*(i-1)+1},IOChannels{2*(i-1)+2});
                    end
                    idx = contains(path,rootObjectTags);
                    tmpA = arrayfun(@(x) sprintf('%03d',x),channelIndices{idx},'Uni',0)';
                    tmpB = repmat(pathCombos(j,1),numel(tmpA),1);
                    rootToLeafMapping = join([tmpB,tmpA],'-');
                    for i = 1:size(channelMaps,1)
                        rootToLeafMapping = rootToLeafMapping(channelMaps{i,1});
                    end
                    idx = find(contains(path,leafObjectTags)==1,1);
                    tmpA = arrayfun(@(x) sprintf('%03d',x),channelIndices{idx},'Uni',0)';
                    tmpB = repmat(pathCombos(j,2),numel(tmpA),1);
                    leafToRootMapping = join([tmpB,tmpA],'-');
                    try
                        for i = size(channelMaps,1):-1:1
                            leafToRootMapping = leafToRootMapping(channelMaps{i,2});
                        end
                    catch
                    end
                    masterRootToLeafMapping = [masterRootToLeafMapping; rootToLeafMapping];
                    masterLeafToRootMapping = [masterLeafToRootMapping; leafToRootMapping];
                end
            end

            [masterRootToLeafMapping,ind] = sort(masterRootToLeafMapping);
            masterLeafToRootMapping = masterLeafToRootMapping(ind);
            mappingTable = table(masterRootToLeafMapping,masterLeafToRootMapping);
        end

        % Menu selected function: SavePatchbayMenu
        function SavePatchbayMenuSelected(app, event)
            pathToMLAPP = fileparts(mfilename('fullpath'));
            defaultPath = fullfile(pathToMLAPP,'PatchBays');
            [saveName,savePath] = uiputfile(defaultPath);
            if saveName == 0
                return
            end
            [~,saveName,saveExt] = fileparts(saveName);
            patchbayPalette = struct;
            for i = 1:numel(app.UIAxes.Children)
                patchbayPalette(i).Type = app.UIAxes.Children(i).Type;
                patchbayPalette(i).Tag = app.UIAxes.Children(i).Tag;
                switch app.UIAxes.Children(i).Type
                    case 'image'
                        patchbayPalette(i).Data = app.UIAxes.Children(i).CData;
                    case 'graphplot'
                        patchbayPalette(i).Data = app.UIAxes.Children(i).UserData;
                end
            end
            save(fullfile(savePath,[saveName,'.mat']),'patchbayPalette','-mat')
        end

        % Menu selected function: LoadPatchbayMenu
        function LoadPatchbayMenuSelected(app, event)
            pathToMLAPP = fileparts(mfilename('fullpath'));
            defaultPath = fullfile(pathToMLAPP,'PatchBays');
            [loadName,loadPath] = uigetfile(defaultPath,'MultiSelect','on');
            if loadName == 0
                return
            end
            cla(app.UIAxes)
            initializeAxes(app)
            initializeObjectList(app)
            initializePatchbayGraph(app)
            
            load(fullfile(loadPath,loadName),'patchbayPalette')
            tags = {patchbayPalette.Tag};
            edgeID = contains(tags,'-');
            nodes = struct2table(patchbayPalette(~edgeID));
            edges = struct2table(patchbayPalette(edgeID));
            nodes = sortrows(nodes,{'Tag','Type'},{'ascend','descend'});
            % Draw Objects
            for i = 1:size(nodes,1)
                switch nodes.Type{i}
                    case 'image'
                        newIcon = nodes.Data{i};
                        tag = nodes.Tag{i};

                        % Get Properties
                        xyCenter = size(newIcon,1,2)./2;
                        viewCenter = sum([xlim(app.UIAxes);ylim(app.UIAxes)],2)/2;

                        % Generate Object Image
                        app.ObjectImage = image(app.UIAxes,newIcon,'XData',[-xyCenter(1) xyCenter(1)]+viewCenter(1),'YData',[-xyCenter(2) xyCenter(2)]+viewCenter(2));
                        app.ObjectImage.Tag = tag;
                        app.ObjectImage.ButtonDownFcn = @app.clickObject;
                        app.ObjectImage.ContextMenu = app.ObjectContextMenu;

                        addNodeToPatchbayGraph(app,tag)
                        app.ObjectList.(tag) = [];
                    case 'graphplot'
                        subgraph = nodes.Data{i};
                        tag = nodes.Tag{i};
                        if isempty(subgraph.Edges)
                            tmp = regexp(subgraph.Nodes.Name,'I\d+$');
                            tmp1 = cellfun(@isempty,tmp);
                            inputNodeNumber = sum(~tmp1);
                            outputNodeNumber = sum(tmp1);
                        else
                            inputNodeNumber = numel(unique(subgraph.Edges.EndNodes(:,1)));
                            outputNodeNumber = numel(unique(subgraph.Edges.EndNodes(:,2)));
                        end

                        %InputNodes
                        spacing = linspace(app.ObjectImage.XData(1),app.ObjectImage.XData(2),inputNodeNumber+2);
                        spacing([1 numel(spacing)]) = [];
                        xdata = spacing;
                        ydata = repmat(app.ObjectImage.YData(1),1,inputNodeNumber);
                        %OutputNodes
                        spacing = linspace(app.ObjectImage.XData(1),app.ObjectImage.XData(2),outputNodeNumber+2);
                        spacing([1 numel(spacing)]) = [];
                        xdata = [xdata spacing];
                        ydata = [ydata repmat(app.ObjectImage.YData(2),1,outputNodeNumber)];

                        % Generate Object Subgraph
                        app.ObjectSubgraphPlot = plot(app.UIAxes,subgraph,'XData',xdata,'YData',ydata);
                        app.ObjectSubgraphPlot.ButtonDownFcn = @app.selectObjectPort;
                        app.ObjectSubgraphPlot.NodeLabel = {};
                        app.ObjectSubgraphPlot.NodeColor = [0 1 0];
                        app.ObjectSubgraphPlot.EdgeColor = [0 1 0];
                        app.ObjectSubgraphPlot.Marker = 'diamond';
                        app.ObjectSubgraphPlot.MarkerSize = 8;
                        app.ObjectSubgraphPlot.Tag = tag;
                        app.ObjectSubgraphPlot.UserData = subgraph;
                        uistack(app.ObjectSubgraphPlot,'down',1)
                end
            end
            
            % Draw Edges
            for i = 1:size(edges,1)
                tag = edges.Tag{i};
                targetSubgraphs = strsplit(tag,'-');
                subgraphNodes = edges.Data{i,2};
                xData = [0 0];
                yData = [0 0];
                for j = 1:2
                    tmpObj = findobj(app.UIAxes.Children,'Tag',targetSubgraphs{j},'Type','graphplot');
                    xData(j) = tmpObj.XData(subgraphNodes(j));
                    yData(j) = tmpObj.YData(subgraphNodes(j));
                end

                edgeGraph = digraph(1,2);
                app.CurrentEdge = plot(app.UIAxes,edgeGraph,'XData',xData,'YData',yData);
                app.CurrentEdge.Marker = 'diamond';
                app.CurrentEdge.MarkerSize = 8;
                app.CurrentEdge.NodeColor = [1 0 0];
                app.CurrentEdge.NodeLabel = {};
                app.CurrentEdge.EdgeColor = [1 0 0];
                app.CurrentEdge.LineWidth = 3;
                app.CurrentEdge.ArrowSize = 15;
                app.CurrentEdge.ArrowPosition = 0.7;
                app.CurrentEdge.PickableParts = 'all';
                app.CurrentEdge.ButtonDownFcn = @app.selectEdge;
                app.CurrentEdge.Tag = tag;
                app.CurrentEdge.UserData = edges.Data(i,:);
                uistack(app.CurrentEdge,'top')

                addEdgeToPatchbayGraph(app)
            end

            cleanupLayout(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NeuroPatchbayUIFigure = uifigure('Visible', 'off');
            app.NeuroPatchbayUIFigure.Name = 'NeuroPatchbay';
            app.NeuroPatchbayUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @NeuroPatchbayUIFigureWindowScrollWheel, true);
            app.NeuroPatchbayUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @NeuroPatchbayUIFigureWindowKeyPress, true);
            app.FileMenu = uimenu(app.NeuroPatchbayUIFigure);
            app.FileMenu.Text = 'File';
            app.NewPatchbayMenu = uimenu(app.FileMenu);
            app.NewPatchbayMenu.MenuSelectedFcn = createCallbackFcn(app, @NewPatchbayMenuSelected, true);
            app.NewPatchbayMenu.Text = 'New Patchbay';
            app.SavePatchbayMenu = uimenu(app.FileMenu);
            app.SavePatchbayMenu.MenuSelectedFcn = createCallbackFcn(app, @SavePatchbayMenuSelected, true);
            app.SavePatchbayMenu.Separator = 'on';
            app.SavePatchbayMenu.Text = 'Save Patchbay';
            app.LoadPatchbayMenu = uimenu(app.FileMenu);
            app.LoadPatchbayMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadPatchbayMenuSelected, true);
            app.LoadPatchbayMenu.Text = 'Load Patchbay';
            app.ActionsMenu = uimenu(app.NeuroPatchbayUIFigure);
            app.ActionsMenu.Text = 'Actions';
            app.RunChannelMatchingMenu = uimenu(app.ActionsMenu);
            app.RunChannelMatchingMenu.MenuSelectedFcn = createCallbackFcn(app, @RunChannelMatchingMenuSelected, true);
            app.RunChannelMatchingMenu.Text = 'Run Channel Matching';
            app.GridLayout = uigridlayout(app.NeuroPatchbayUIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XLim = [-2000 2000];
            app.UIAxes.YLim = [-2000 2000];
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.YAxisLocation = 'origin';
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.ComponentsLabel = uilabel(app.GridLayout);
            app.ComponentsLabel.Text = 'Components';
            app.PatchbayPaletteLabel = uilabel(app.GridLayout);
            app.PatchbayPaletteLabel.Text = 'Patchbay Palette';
            app.Tree = uitree(app.GridLayout);
            app.ObjectContextMenu = uicontextmenu(app.NeuroPatchbayUIFigure);
            app.DeleteMenu = uimenu(app.ObjectContextMenu);
            app.DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @deleteSelectedObjectCallback, true);
            app.DeleteMenu.Text = 'Delete';
            app.UIAxesContextMenu = uicontextmenu(app.NeuroPatchbayUIFigure);
            app.ResetViewMenu = uimenu(app.UIAxesContextMenu);
            app.ResetViewMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetViewMenuSelected, true);
            app.ResetViewMenu.Text = 'Reset View';
            app.ViewAllObjectsMenu = uimenu(app.UIAxesContextMenu);
            app.ViewAllObjectsMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewAllObjectsMenuSelected, true);
            app.ViewAllObjectsMenu.Text = 'View All Objects';
            app.CleanupLayoutMenu = uimenu(app.UIAxesContextMenu);
            app.CleanupLayoutMenu.MenuSelectedFcn = createCallbackFcn(app, @CleanupLayoutMenuSelected, true);
            app.CleanupLayoutMenu.Text = 'Cleanup Layout';
            % Assign app.UIAxesContextMenu
            app.UIAxes.ContextMenu = app.UIAxesContextMenu;
            % Show the figure after all components are created
            app.NeuroPatchbayUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NeuroPatchbay

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.NeuroPatchbayUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.NeuroPatchbayUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NeuroPatchbayUIFigure)
        end
    end
end