classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        UIAxes          matlab.ui.control.UIAxes
        ContextMenu     matlab.ui.container.ContextMenu
        DeleteNodeMenu  matlab.ui.container.Menu
        Menu2           matlab.ui.container.Menu
    end

    
    properties (Access = private)
        graph
        graphPlot % Description
        SelectedNode
    end
    
    methods (Access = private)
        
        function selectNode(app,source,event)
            switch event.Button
                case 1
                    [~,app.SelectedNode] = min(pdist2([event.Source.XData;event.Source.YData]',event.IntersectionPoint(1:2)));
                    app.UIFigure.WindowButtonMotionFcn = {@app.dragNode,source,app.SelectedNode};
                    app.UIFigure.WindowButtonUpFcn = @app.dragNodeDone;
                case 2
                case 3
                    [~,app.SelectedNode] = min(pdist2([event.Source.XData;event.Source.YData]',event.IntersectionPoint(1:2)));


            end
        end

        function dragNode(~,~,~,callingSource,ind)
            callingSource.XData(ind) = callingSource.Parent.CurrentPoint(1,1);
            callingSource.YData(ind) = callingSource.Parent.CurrentPoint(1,2);
        end

        function dragNodeDone(app,~,~)
            app.UIFigure.WindowButtonMotionFcn = [];
            app.UIFigure.WindowButtonUpFcn = [];
        end

        function overwriteGraphPlot(app)
            app.UIAxes.Children = plot(app.UIAxes,app.graph);
            app.UIAxes.Children.ButtonDownFcn = @app.selectNode;
            app.UIAxes.Children.ContextMenu = app.ContextMenu;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.graph = digraph(round(rand(10)));
            app.graphPlot = plot(app.UIAxes,app.graph);
            app.graphPlot.ButtonDownFcn = @app.selectNode;
            app.graphPlot.ContextMenu = app.ContextMenu;
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            switch key
                case 'f12'
                    if any(contains(event.Modifier,'control'))
                        keyboard
                    end
            end
        end

        % Menu selected function: DeleteNodeMenu
        function DeleteNodeMenuSelected(app, event)
            idx = isgraphics(app.UIAxes.Children,'GraphPlot');
            xdata = app.UIAxes.Children(idx).XData;
            ydata = app.UIAxes.Children(idx).YData;
            xdata(app.SelectedNode) = [];
            ydata(app.SelectedNode) = [];
            app.graph = rmnode(app.graph,app.SelectedNode);
            app.UIAxes.Children(idx) = plot(app.UIAxes,app.graph,'XData',xdata,'YData',ydata);
            app.UIAxes.Children(idx).ButtonDownFcn = @app.selectNode;
            app.UIAxes.Children(idx).ContextMenu = app.ContextMenu;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.DeleteNodeMenu = uimenu(app.ContextMenu);
            app.DeleteNodeMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteNodeMenuSelected, true);
            app.DeleteNodeMenu.Text = 'Delete Node';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end